classdef Interfaz_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Cargar                          matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        AudioMixerLabel                 matlab.ui.control.Label
        Play                            matlab.ui.control.Button
        Pause                           matlab.ui.control.Button
        Resume                          matlab.ui.control.Button
        Stop                            matlab.ui.control.Button
        FiltroDropDownLabel             matlab.ui.control.Label
        FiltroDropDown                  matlab.ui.control.DropDown
        FiltroDropDown_2Label           matlab.ui.control.Label
        FiltroDropDown_2                matlab.ui.control.DropDown
        Canal1Label                     matlab.ui.control.Label
        Canal2Label                     matlab.ui.control.Label
        EfectoDropDownLabel             matlab.ui.control.Label
        EfectoDropDown                  matlab.ui.control.DropDown
        EfectoDropDown_2Label           matlab.ui.control.Label
        EfectoDropDown_2                matlab.ui.control.DropDown
        Switch                          matlab.ui.control.Switch
        Switch_2                        matlab.ui.control.Switch
        GananciaSpinnerLabel            matlab.ui.control.Label
        GananciaSpinner                 matlab.ui.control.Spinner
        GananciaSpinner_2Label          matlab.ui.control.Label
        GananciaSpinner_2               matlab.ui.control.Spinner
        NuevoarchivoEditFieldLabel      matlab.ui.control.Label
        NuevoarchivoEditField           matlab.ui.control.EditField
        GenerarButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        GenerarButton_2                 matlab.ui.control.Button
        GuardarVariablesEditFieldLabel  matlab.ui.control.Label
        GuardarVariablesEditField       matlab.ui.control.EditField
        matLabel                        matlab.ui.control.Label
        wavLabel                        matlab.ui.control.Label
        GenerarSealdelFiltroButton      matlab.ui.control.Button
        GenerarSealdeEntradaButton      matlab.ui.control.Button
        GenerarSealdeSalidaButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        archivo     % Description
        player
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Cargar
        function CargarButtonPushed(app, event)
            [file,path] = uigetfile({'*.wav;*.mp3', 'Audio Files'});
            if isequal(file,0)
                app.EditField.BackgroundColor = 'red';
            else
                app.EditField.BackgroundColor = 'white';
                app.EditField.Value = file;
                app.archivo = file;
                [audio,Fs] = audioread(app.archivo);
                app.player = audioplayer(audio,Fs);
            end
        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            play(app.player);
        end

        % Button pushed function: Pause
        function PauseButtonPushed(app, event)
            pause(app.player);
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            stop(app.player);
        end

        % Button pushed function: Resume
        function ResumeButtonPushed(app, event)
            resume(app.player);
        end

        % Value changed function: FiltroDropDown
        function FiltroDropDownValueChanged(app, event)
            value = app.FiltroDropDown.Value;
            %switch val
            %    case {'Pasa-altas', 'Pasabajas', 'Pasabandas', 'Notch'}
                    
            %end
        end

        % Button pushed function: GenerarSealdeEntradaButton
        function GenerarSealdeEntradaButtonPushed(app, event)
            [x,~] = audioread(app.archivo);
            t1 = (0:(1/(length(x)-1)):1);
            plot(app.UIAxes_2, t1,x);
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            [x_new,Fs_new] = audioread(app.archivo);
            Nuevo_archivo = app.NuevoarchivoEditField.Value+".wav";
            audiowrite(Nuevo_archivo,x_new,Fs_new);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Cargar = uibutton(app.UIFigure, 'push');
            app.Cargar.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.Cargar.Icon = 'upload.png';
            app.Cargar.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.AudioMixerLabel = uilabel(app.UIFigure);
            app.AudioMixerLabel.Text = 'Audio Mixer';
            app.Play = uibutton(app.UIFigure, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Icon = 'play.png';
            app.Play.Text = '';
            app.Pause = uibutton(app.UIFigure, 'push');
            app.Pause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.Pause.Icon = 'pause.png';
            app.Pause.Text = '';
            app.Resume = uibutton(app.UIFigure, 'push');
            app.Resume.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.Resume.Icon = 'resume.png';
            app.Resume.Text = '';
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Icon = 'stop.png';
            app.Stop.Text = '';
            app.FiltroDropDownLabel = uilabel(app.UIFigure);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.UIFigure);
            app.FiltroDropDown.Items = {'Pasa-altas', 'Pasabajas', 'Pasabandas', 'Notch'};
            app.FiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDownValueChanged, true);
            app.FiltroDropDown.Value = 'Pasa-altas';
            app.FiltroDropDown_2Label = uilabel(app.UIFigure);
            app.FiltroDropDown_2Label.Text = 'Filtro';
            app.FiltroDropDown_2 = uidropdown(app.UIFigure);
            app.FiltroDropDown_2.Items = {'Pasa-altas', 'Pasabajas', 'Pasabandas', 'Notch'};
            app.FiltroDropDown_2.Value = 'Pasa-altas';
            app.Canal1Label = uilabel(app.UIFigure);
            app.Canal1Label.Text = 'Canal 1';
            app.Canal2Label = uilabel(app.UIFigure);
            app.Canal2Label.Text = 'Canal 2';
            app.EfectoDropDownLabel = uilabel(app.UIFigure);
            app.EfectoDropDownLabel.Text = 'Efecto';
            app.EfectoDropDown = uidropdown(app.UIFigure);
            app.EfectoDropDown.Items = {'Eco', 'Vibrato', 'Distorsión', 'Reverberación', 'Flanger'};
            app.EfectoDropDown.Value = 'Eco';
            app.EfectoDropDown_2Label = uilabel(app.UIFigure);
            app.EfectoDropDown_2Label.Text = 'Efecto';
            app.EfectoDropDown_2 = uidropdown(app.UIFigure);
            app.EfectoDropDown_2.Items = {'Eco', 'Vibrato', 'Distorsión', 'Reverberación', 'Flanger'};
            app.EfectoDropDown_2.Value = 'Eco';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.GananciaSpinnerLabel = uilabel(app.UIFigure);
            app.GananciaSpinnerLabel.Text = 'Ganancia';
            app.GananciaSpinner = uispinner(app.UIFigure);
            app.GananciaSpinner.Step = 0.5;
            app.GananciaSpinner_2Label = uilabel(app.UIFigure);
            app.GananciaSpinner_2Label.Text = 'Ganancia';
            app.GananciaSpinner_2 = uispinner(app.UIFigure);
            app.GananciaSpinner_2.Step = 0.5;
            app.NuevoarchivoEditFieldLabel = uilabel(app.UIFigure);
            app.NuevoarchivoEditFieldLabel.Text = 'Nuevo archivo';
            app.NuevoarchivoEditField = uieditfield(app.UIFigure, 'text');
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Respuesta de los filtros')
            xlabel(app.UIAxes, 'Frecuencia (Hz)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Señal de Entrada')
            xlabel(app.UIAxes_2, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_2, 'Amplitud')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Señal de Salida')
            xlabel(app.UIAxes_3, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_3, 'Amplitud')
            app.GenerarButton_2 = uibutton(app.UIFigure, 'push');
            app.GenerarButton_2.Text = 'Generar';
            app.GuardarVariablesEditFieldLabel = uilabel(app.UIFigure);
            app.GuardarVariablesEditFieldLabel.Text = 'Guardar Variables';
            app.GuardarVariablesEditField = uieditfield(app.UIFigure, 'text');
            app.matLabel = uilabel(app.UIFigure);
            app.matLabel.Text = '.mat';
            app.wavLabel = uilabel(app.UIFigure);
            app.wavLabel.Text = '.wav';
            app.GenerarSealdelFiltroButton = uibutton(app.UIFigure, 'push');
            app.GenerarSealdelFiltroButton.Text = 'Generar Señal del Filtro';
            app.GenerarSealdeEntradaButton = uibutton(app.UIFigure, 'push');
            app.GenerarSealdeEntradaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarSealdeEntradaButtonPushed, true);
            app.GenerarSealdeEntradaButton.Text = 'Generar Señal de Entrada';
            app.GenerarSealdeSalidaButton = uibutton(app.UIFigure, 'push');
            app.GenerarSealdeSalidaButton.Text = 'Generar Señal de Salida';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end